classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DropDown                    matlab.ui.control.DropDown
        WhichdimensionwouldyouliketodisplayLabel  matlab.ui.control.Label
        MultidimensionalArrayLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            % This line is automatically generated by MATLAB when creating
            % a callback function. "item" is whatever the user selects from
            % the dropdown list
            item = event.InteractionInformation.Item;
            
            % ----- Define empty 3D Array of size 100x100x100 -----
            array = zeros(100,100,100);

            % ----- Update array values -----
            
            % First for loop for first dimension
            for i = 1:size(array)
                % Second for loop for second dimension
                for j = 1:100
                    % Third for loop for third dimension
                    for k = 1:100
                        % Update the array valyes
                        array(i,j,k) = i * j * k * 0.001;
                    end
                end
            end

            % ----- Plot the updated arrays based on user selection ----

            % If the user selects "1"
            if item == 1
                % Plot the grayscale image, with the third dimension as "1"
                imshow(array(:,:,1), 'Parent',app.UIAxes);
            
            % If the user selects "2"
            elseif item == 2
                % Plot the grayscale image, with the third dimension as "2"
                imshow(array(:,:,2), 'Parent',app.UIAxes);
            
            % If the user selects "3"
            elseif item == 3
                % Plot the grayscale image, with the third dimension as "3"
                imshow(array(:,:,3), 'Parent',app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.MultidimensionalArrayLabel = uilabel(app.UIFigure);
            app.MultidimensionalArrayLabel.Text = 'Multidimensional Array';
            app.WhichdimensionwouldyouliketodisplayLabel = uilabel(app.UIFigure);
            app.WhichdimensionwouldyouliketodisplayLabel.Text = 'Which dimension would you like to display?';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'1', '2', '3'};
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end